classdef COVID19_Matlab_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        place                          matlab.ui.container.ButtonGroup
        all                            matlab.ui.control.ToggleButton
        specific                       matlab.ui.control.ToggleButton
        SubsetofCountriesListBoxLabel  matlab.ui.control.Label
        countries                      matlab.ui.control.ListBox
        TabGroup                       matlab.ui.container.TabGroup
        InfectionsTab                  matlab.ui.container.Tab
        ax_infections                  matlab.ui.control.UIAxes
        DeathsTab                      matlab.ui.container.Tab
        ax_deaths                      matlab.ui.control.UIAxes
        GrowthRatebyCountryTab         matlab.ui.container.Tab
        UpdateButton                   matlab.ui.control.Button
        gr_table                       matlab.ui.control.Table
        gr_hist                        matlab.ui.control.UIAxes
        PlotTypeButtonGroup            matlab.ui.container.ButtonGroup
        lin                            matlab.ui.control.ToggleButton
        log                            matlab.ui.control.ToggleButton
        add_trend_lines                matlab.ui.control.CheckBox
        UsePreviousEditFieldLabel      matlab.ui.control.Label
        days_for_trend                 matlab.ui.control.NumericEditField
        daystocomputetrendLabel        matlab.ui.control.Label
    end

    
    properties (Access = private)
    end
    
    properties (Access = public)
        DATA % Description
        DATA_Deaths
        Countries
        States
        inf_vs_t
        dth_vs_t
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath('../COVID-19/csse_covid_19_data/csse_covid_19_time_series/')
            
            try
                Y = importdata('time_series_19-covid-Confirmed.csv');
            catch
                [~, pathname, ~] = uigetfile('time_series_19-covid-Confirmed.csv','Navigate to find the File time_series_19-covid-Confirmed.csv');
                addpath(pathname);
                Y = importdata('time_series_19-covid-Confirmed.csv');
            end
            
            app.DATA = Y.data;
            app.Countries = Y.textdata(2:end,2);
            app.States = Y.textdata(2:end,1);
            
            Y = importdata('time_series_19-covid-Deaths.csv');
            app.DATA_Deaths = Y.data;
            
            app.countries.Items = unique(app.Countries);
            app.countries.Value = 'US';
            
            placeSelectionChanged(app)
        end

        % Selection changed function: place
        function placeSelectionChanged(app, event)
            if app.specific.Value
                app.countries.Items = unique(app.Countries);
                app.countries.Value = 'US';
            end
            
            Make_Plot(app)
        end

        % Callback function: PlotTypeButtonGroup, days_for_trend
        function PlotTypeButtonGroupSelectionChanged(app, event)
            Make_Plot(app)
        end

        % Value changed function: countries
        function countriesValueChanged(app, event)
            Make_Plot(app)
        end

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)
            Make_Growth_Rate_Table(app)        
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.place = uibuttongroup(app.UIFigure);
            app.place.SelectionChangedFcn = createCallbackFcn(app, @placeSelectionChanged, true);
            app.place.Title = 'All Countries or Selected Subset of Countries';
            app.all = uitogglebutton(app.place);
            app.all.Text = 'All Countries';
            app.all.Value = true;
            app.specific = uitogglebutton(app.place);
            app.specific.Text = 'Specific Countires';
            app.SubsetofCountriesListBoxLabel = uilabel(app.UIFigure);
            app.SubsetofCountriesListBoxLabel.Text = 'Subset of Countries';
            app.countries = uilistbox(app.UIFigure);
            app.countries.Multiselect = 'on';
            app.countries.ValueChangedFcn = createCallbackFcn(app, @countriesValueChanged, true);
            app.countries.Value = {'Item 1'};
            app.TabGroup = uitabgroup(app.UIFigure);
            app.InfectionsTab = uitab(app.TabGroup);
            app.InfectionsTab.Title = 'Infections';
            app.ax_infections = uiaxes(app.InfectionsTab);
            title(app.ax_infections, 'Infections over time')
            xlabel(app.ax_infections, 'Time (days)')
            ylabel(app.ax_infections, 'Infections')
            app.DeathsTab = uitab(app.TabGroup);
            app.DeathsTab.Title = 'Deaths';
            app.ax_deaths = uiaxes(app.DeathsTab);
            title(app.ax_deaths, 'Deaths over time')
            xlabel(app.ax_deaths, 'Time (days)')
            ylabel(app.ax_deaths, 'Deaths')
            app.GrowthRatebyCountryTab = uitab(app.TabGroup);
            app.GrowthRatebyCountryTab.Title = 'Growth Rate by Country';
            app.UpdateButton = uibutton(app.GrowthRatebyCountryTab, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Text = 'Update';
            app.gr_table = uitable(app.GrowthRatebyCountryTab);
            app.gr_table.ColumnName = {'Country'; 'Doubling Time (days)'};
            app.gr_table.RowName = {};
            app.gr_hist = uiaxes(app.GrowthRatebyCountryTab);
            title(app.gr_hist, 'Histogram')
            xlabel(app.gr_hist, 'Doubling Time (days)')
            ylabel(app.gr_hist, 'Probability Density')
            app.PlotTypeButtonGroup = uibuttongroup(app.UIFigure);
            app.PlotTypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @PlotTypeButtonGroupSelectionChanged, true);
            app.PlotTypeButtonGroup.Title = 'Plot Type';
            app.lin = uitogglebutton(app.PlotTypeButtonGroup);
            app.lin.Text = 'Linear';
            app.log = uitogglebutton(app.PlotTypeButtonGroup);
            app.log.Text = 'Logarithmic';
            app.log.Value = true;
            app.add_trend_lines = uicheckbox(app.UIFigure);
            app.add_trend_lines.Text = 'Include Trend lines';
            app.add_trend_lines.Value = true;
            app.UsePreviousEditFieldLabel = uilabel(app.UIFigure);
            app.UsePreviousEditFieldLabel.Text = 'Use Previous';
            app.days_for_trend = uieditfield(app.UIFigure, 'numeric');
            app.days_for_trend.Limits = [3 20];
            app.days_for_trend.RoundFractionalValues = 'on';
            app.days_for_trend.ValueChangedFcn = createCallbackFcn(app, @PlotTypeButtonGroupSelectionChanged, true);
            app.days_for_trend.Value = 10;
            app.daystocomputetrendLabel = uilabel(app.UIFigure);
            app.daystocomputetrendLabel.Text = 'days to compute trend';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = COVID19_Matlab_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end